classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWG                    matlab.ui.Figure
        WaveTypeKnobLabel      matlab.ui.control.Label
        WaveTypeKnob           matlab.ui.control.DiscreteKnob
        LoadWaveButton         matlab.ui.control.Button
        SerialConnectionPanel  matlab.ui.container.Panel
        Refresh                matlab.ui.control.Button
        PortLabel_2            matlab.ui.control.Label
        PortList               matlab.ui.control.DropDown
        BCon                   matlab.ui.control.Button
        BDiscon                matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        FrequencyField         matlab.ui.control.NumericEditField
        AmplitudeField         matlab.ui.control.NumericEditField
        PhaseField             matlab.ui.control.NumericEditField
        FrequencyHzLabel       matlab.ui.control.Label
        FrequencyKnob          matlab.ui.control.Slider
        PhaseDegreesLabel      matlab.ui.control.Label
        PhaseKnob              matlab.ui.control.Slider
        AmplitudeVLabel        matlab.ui.control.Label
        AmplitudeKnob          matlab.ui.control.Slider
        CommandLabel           matlab.ui.control.Label
        txt                    matlab.ui.control.EditField
    end

    
    properties (Access = private)
        TIME_STEP = 0.2
        s % Description
        wavetype='o' % Description
        frequency=0 % Description
        amplitude=0 % Description
        phase=0 % Description
        arbwave=zeros(1,1000) % Description
        file % Description
        path 
        t % Description
        arbwave_digits % Description
        command
    end
    
    methods (Access = private)
        
        function sendCommand(app)
            fwrite(app.s, app.command);
            app.txt.Value=app.command;
        end
        
        function updateGUI(app)
            if(app.wavetype=='o')
                app.WaveTypeKnob.Value="Off";
            elseif(app.wavetype=='s')
                app.WaveTypeKnob.Value="Sine";
            elseif(app.wavetype=='t')
                app.WaveTypeKnob.Value="Triangle";
            elseif(app.wavetype=='q')
                app.WaveTypeKnob.Value="Square";
            elseif(app.wavetype=='a')
                app.WaveTypeKnob.Value="Arbitrary";
            end
            app.FrequencyField.Value=app.frequency;
            app.AmplitudeField.Value=app.amplitude/10;
            app.PhaseField.Value=app.phase;
            app.FrequencyKnob.Value=app.FrequencyField.Value;
            app.AmplitudeKnob.Value=app.AmplitudeField.Value;
            app.PhaseKnob.Value = app.PhaseField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            try 
                app.s = serial(app.PortList.Value, 'BaudRate', 115200);
                app.Lamp.Color=[0 1 0];
                fopen(app.s);
                
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            try  
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color=[1 0 0];
            end
                app.wavetype='o';
                app.frequency=0;
                app.amplitude=0;
                app.phase=0;
                updateGUI(app);
                app.txt.Value='';
        end

        % Value changed function: WaveTypeKnob
        function WaveTypeKnobValueChanged(app, event)
            if(app.WaveTypeKnob.Value=="Off")
                app.wavetype='o';
            elseif(app.WaveTypeKnob.Value=="Sine")
                app.wavetype='s';
            elseif(app.WaveTypeKnob.Value=="Triangle")
                app.wavetype='t';
            elseif(app.WaveTypeKnob.Value=="Square")
                app.wavetype='q';
            elseif(app.WaveTypeKnob.Value=="Arbitrary")
                app.wavetype='a';
            end
            try
                app.command=sprintf("%s\r", app.wavetype);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            app.frequency=fix(app.FrequencyKnob.Value);
            app.FrequencyField.Value=fix(app.frequency);
            try
                app.command=sprintf("f%03d\r", app.frequency);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: FrequencyField
        function FrequencyFieldValueChanged(app, event)
            app.FrequencyKnob.Value=app.FrequencyField.Value;
            app.frequency=app.FrequencyField.Value;
            try
                app.command=sprintf("f%03d\r", app.frequency);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            app.amplitude=fix(app.AmplitudeKnob.Value*10);
            app.AmplitudeField.Value=round(app.amplitude/10,1);
            try
                app.command=sprintf("v%02d\r", app.amplitude);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: AmplitudeField
        function AmplitudeFieldValueChanged(app, event)
            app.AmplitudeKnob.Value=app.AmplitudeField.Value;
            app.amplitude=app.AmplitudeField.Value*10;
            try
                app.command=sprintf("v%02d\r", app.amplitude);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            app.phase = fix(app.PhaseKnob.Value);
            app.PhaseField.Value = fix(app.PhaseKnob.Value);
            try
                app.command=sprintf("p%03d\r", app.phase);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Value changed function: PhaseField
        function PhaseFieldValueChanged(app, event)
            app.PhaseKnob.Value = app.PhaseField.Value;
            app.phase = app.PhaseField.Value;
            try
                app.command=sprintf("p%03d\r", app.phase);
                sendCommand(app)
            end
            updateGUI(app);
        end

        % Button pushed function: LoadWaveButton
        function LoadWaveButtonPushed(app, event)
            try
                [app.file, app.path] = uigetfile('*.txt');
                app.arbwave = readtable(sprintf("%s%s", app.path, app.file), 'ReadVariableNames', false);
                app.arbwave = table2array(app.arbwave)';
                app.LoadWaveButton.Text="Uploading...";
            end
            
            fprintf(app.s, 'l');
            for i=2:length(app.arbwave)
                try
                    fprintf(app.s, sprintf("%s", cell2mat(app.arbwave(i))));
                    pause(0.001)
                end
            end
            for i=1:10
                try
                    fprintf(app.s, 'r');
                    pause(0.001)
                end
            end
            
            app.LoadWaveButton.Text="Load Wave";
            updateGUI(app);
        end

        % Button down function: AWG
        function AWGButtonDown(app, event)
            updateGUI(app);
            drawnow;
        end

        % Value changed function: txt
        function txtValueChanged(app, event)
            app.command=sprintf("%s\r", app.txt.Value);
            sendCommand(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWG = uifigure('Visible', 'off');
            app.AWG.Name = 'Diogo Vala & Beatriz Silva';
            app.AWG.ButtonDownFcn = createCallbackFcn(app, @AWGButtonDown, true);
            app.WaveTypeKnobLabel = uilabel(app.AWG);
            app.WaveTypeKnobLabel.Text = 'WaveType';
            app.WaveTypeKnob = uiknob(app.AWG, 'discrete');
            app.WaveTypeKnob.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Arbitrary'};
            app.WaveTypeKnob.ValueChangedFcn = createCallbackFcn(app, @WaveTypeKnobValueChanged, true);
            app.LoadWaveButton = uibutton(app.AWG, 'push');
            app.LoadWaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveButtonPushed, true);
            app.LoadWaveButton.Text = 'Load Wave';
            app.SerialConnectionPanel = uipanel(app.AWG);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.Refresh = uibutton(app.SerialConnectionPanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel_2 = uilabel(app.SerialConnectionPanel);
            app.PortLabel_2.Text = 'Port';
            app.PortList = uidropdown(app.SerialConnectionPanel);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.BCon = uibutton(app.SerialConnectionPanel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.SerialConnectionPanel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.Lamp = uilamp(app.SerialConnectionPanel);
            app.FrequencyField = uieditfield(app.AWG, 'numeric');
            app.FrequencyField.ValueChangedFcn = createCallbackFcn(app, @FrequencyFieldValueChanged, true);
            app.AmplitudeField = uieditfield(app.AWG, 'numeric');
            app.AmplitudeField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeFieldValueChanged, true);
            app.PhaseField = uieditfield(app.AWG, 'numeric');
            app.PhaseField.ValueChangedFcn = createCallbackFcn(app, @PhaseFieldValueChanged, true);
            app.FrequencyHzLabel = uilabel(app.AWG);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.FrequencyKnob = uislider(app.AWG);
            app.FrequencyKnob.Limits = [0 300];
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.AmplitudeVLabel = uilabel(app.AWG);
            app.AmplitudeVLabel.Text = 'Amplitude (V)';
            app.AmplitudeKnob = uislider(app.AWG);
            app.AmplitudeKnob.Limits = [0 3.3];
            app.AmplitudeKnob.MajorTicks = [0 1.1 2.2 3.3];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.PhaseDegreesLabel = uilabel(app.AWG);
            app.PhaseDegreesLabel.Text = 'Phase (Degrees)';
            app.PhaseKnob = uislider(app.AWG);
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            app.CommandLabel = uilabel(app.AWG);
            app.CommandLabel.Text = 'Command';
            app.txt = uieditfield(app.AWG, 'text');
            app.txt.ValueChangedFcn = createCallbackFcn(app, @txtValueChanged, true);
            % Show the figure after all components are created
            app.AWG.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AWG)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWG)
        end
    end
end